classdef EquiMotion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EquiMotionTaskUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        EquiMotionTaskGeneralPanel      matlab.ui.container.Panel
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel       matlab.ui.control.Label
        PixelsPerCmEditField            matlab.ui.control.NumericEditField
        FixationXEditFieldLabel         matlab.ui.control.Label
        FixationXEditField              matlab.ui.control.NumericEditField
        ELDummySwitchLabel              matlab.ui.control.Label
        ELDummySwitch                   matlab.ui.control.RockerSwitch
        DebugModeSwitchLabel            matlab.ui.control.Label
        DebugModeSwitch                 matlab.ui.control.RockerSwitch
        FixationYEditFieldLabel         matlab.ui.control.Label
        FixationYEditField              matlab.ui.control.NumericEditField
        FixInitiationEditFieldLabel     matlab.ui.control.Label
        FixInitiationEditField          matlab.ui.control.NumericEditField
        Fix1stDurationEditFieldLabel    matlab.ui.control.Label
        Fix1stDurationEditField         matlab.ui.control.NumericEditField
        FixationDiameterEditFieldLabel  matlab.ui.control.Label
        FixationDiameterEditField       matlab.ui.control.NumericEditField
        InfoEditFieldLabel              matlab.ui.control.Label
        InfoEditField                   matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        CalibrationEditFieldLabel       matlab.ui.control.Label
        CalibrationEditField            matlab.ui.control.EditField
        CButton                         matlab.ui.control.Button
        SPButton                        matlab.ui.control.Button
        SendTriggersSwitchLabel         matlab.ui.control.Label
        SendTriggersSwitch              matlab.ui.control.RockerSwitch
        ColorDepthDropDownLabel         matlab.ui.control.Label
        ColorDepthDropDown              matlab.ui.control.DropDown
        StimulusOptionsPanel            matlab.ui.container.Panel
        FixedColorEditFieldLabel        matlab.ui.control.Label
        FixedColorEditField             matlab.ui.control.EditField
        VarColorStartEditFieldLabel     matlab.ui.control.Label
        VarColorStartEditField          matlab.ui.control.EditField
        VarColorEndEditFieldLabel       matlab.ui.control.Label
        VarColorEndEditField            matlab.ui.control.EditField
        VarColorStepsEditFieldLabel     matlab.ui.control.Label
        VarColorStepsEditField          matlab.ui.control.EditField
        SizeEditFieldLabel              matlab.ui.control.Label
        SizeEditField                   matlab.ui.control.NumericEditField
        TrialDurationsEditFieldLabel    matlab.ui.control.Label
        TrialDurationsEditField         matlab.ui.control.NumericEditField
        BackgroundColorEditFieldLabel   matlab.ui.control.Label
        BackgroundColorEditField        matlab.ui.control.EditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        TrialNumberEditFieldLabel       matlab.ui.control.Label
        TrialNumberEditField            matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel         matlab.ui.control.Label
        SmoothingEditField              matlab.ui.control.NumericEditField
        sigma1                          matlab.ui.control.NumericEditField
        sigma2                          matlab.ui.control.NumericEditField
        IntertrialIntervalsLabel        matlab.ui.control.Label
        IntertrialIntervalsEditField    matlab.ui.control.NumericEditField
        ControlPanel                    matlab.ui.container.Panel
        GOButton                        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        ExperimentalRunTab              matlab.ui.container.Tab
        IsoLumAxes                      matlab.ui.control.UIAxes
        IsoLumAxesPower                 matlab.ui.control.UIAxes
        IsoLumAxesAverage               matlab.ui.control.UIAxes
        MoreSettingsTab                 matlab.ui.container.Tab
        EyelinkPanel                    matlab.ui.container.Panel
        SampleRateKnobLabel             matlab.ui.control.Label
        SampleRateKnob                  matlab.ui.control.DiscreteKnob
        CalibPointsKnobLabel            matlab.ui.control.Label
        CalibPointsKnob                 matlab.ui.control.DiscreteKnob
        TargetFixationOptionsPanel      matlab.ui.container.Panel
        TargetIntiationEditFieldLabel   matlab.ui.control.Label
        TargetIntiationEditField        matlab.ui.control.NumericEditField
        TargetMaintainEditFieldLabel    matlab.ui.control.Label
        TargetMaintainEditField         matlab.ui.control.NumericEditField
        TargetDiameterEditFieldLabel    matlab.ui.control.Label
        TargetDiameterEditField         matlab.ui.control.NumericEditField
        OthertrainingparametersPanel    matlab.ui.container.Panel
        InterTrialDelayEditFieldLabel   matlab.ui.control.Label
        InterTrialDelayEditField        matlab.ui.control.NumericEditField
        FixOnlyRewardEditFieldLabel     matlab.ui.control.Label
        FixOnlyRewardEditField          matlab.ui.control.NumericEditField
        ExclusionZoneEditFieldLabel     matlab.ui.control.Label
        ExclusionZoneEditField          matlab.ui.control.EditField
        ArduinoPanel                    matlab.ui.container.Panel
        SerialPortNameEditFieldLabel    matlab.ui.control.Label
        SerialPortNameEditField         matlab.ui.control.EditField
        SendRewardSwitchLabel           matlab.ui.control.Label
        SendRewardSwitch                matlab.ui.control.RockerSwitch
    end

 
 properties (Access = public)
  ana % parsed settings
 end
 
 methods (Access = private)
  
  function parseSettings(app)
       app.ana = [];
       app.ana.taskName = "EquiMotion";
       app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
       app.ana.subject = app.SubjectEditField.Value;
       app.ana.comments = app.InfoEditField.Value;
       app.ana.ResultDir = app.SavePathEditField.Value;
       app.ana.gammaTable = app.CalibrationEditField.Value;
       app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
       app.ana.distance = app.DistanceEditField.Value;
       app.ana.fixX = app.FixationXEditField.Value;
       app.ana.fixY = app.FixationYEditField.Value;
       app.ana.firstFixInit = app.FixInitiationEditField.Value;
       app.ana.firstFixDiameter = app.FixationDiameterEditField.Value;
       app.ana.firstFixTime = app.Fix1stDurationEditField.Value;
       app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value);
       app.ana.colorFixed = str2num(app.FixedColorEditField.Value);
       app.ana.colorStart = str2num(app.VarColorStartEditField.Value);
       app.ana.colorEnd = str2num(app.VarColorEndEditField.Value);
       app.ana.colorStep = str2num(app.VarColorStepsEditField.Value);
       app.ana.size = app.SizeEditField.Value;
       app.ana.sigma = app.SmoothingEditField.Value;
       app.ana.sigma1 = app.sigma1.Value;
       app.ana.sigma2 = app.sigma2.Value;
       app.ana.trialDuration = app.TrialDurationsEditField.Value;
       app.ana.trialInterval = app.IntertrialIntervalsEditField.Value;
       app.ana.trialNumber = app.TrialNumberEditField.Value;
       app.ana.frequency = app.FrequencyEditField.Value;
       app.ana.sampleRate = str2num(app.SampleRateKnob.Value);
       app.ana.calibrationStyle = app.CalibPointsKnob.Value;
       app.ana.arduinoPort = app.SerialPortNameEditField.Value;
       app.ana.bitDepth = app.ColorDepthDropDown.Value;
       
       if strcmpi(app.DebugModeSwitch.Value,'On')
        app.ana.debug = true;
        app.ana.windowed = [0 0 1000 1000];
       else
        app.ana.debug = false;
        app.ana.windowed = [];
       end
       if strcmpi(app.ELDummySwitch.Value,'On')
        app.ana.isDummy = true;
       else
        app.ana.isDummy = false;
       end
       if strcmpi(app.SendTriggersSwitch.Value,'On')
           app.ana.sendTrigger = true;
       else
           app.ana.sendTrigger = false;
       end
       if strcmpi(app.SendRewardSwitch.Value,'On')
           app.ana.sendReward = true;
       else
           app.ana.sendReward = false;
       end
       app.ana.plotAxis1 = app.IsoLumAxes;
       app.ana.plotAxis2 = app.IsoLumAxesPower;
       app.ana.plotAxis3 = app.IsoLumAxesAverage;
  end

 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setPath(app)
            s=screenManager();
            app.SavePathEditField.Value=s.paths.savedData;
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            
            [f, p] = uigetfile('*.mat','Select a Calibration File');
            app.CalibrationEditField.Value = [p f];
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           delete(app);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           runEquiMotion(app.ana)
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
            p=app.setPath();
            cd(p);
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.parseSettings();
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EquiMotionTaskUIFigure = uifigure('Visible', 'off');
            app.EquiMotionTaskUIFigure.Name = 'EquiMotion Task';
            app.TabGroup = uitabgroup(app.EquiMotionTaskUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.EquiMotionTaskGeneralPanel = uipanel(app.SettingsTab);
            app.EquiMotionTaskGeneralPanel.Title = 'EquiMotion Task: General';
            app.DistanceEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'numeric');
            app.DistanceEditField.Value = 64;
            app.PixelsPerCmEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'numeric');
            app.PixelsPerCmEditField.Value = 36;
            app.FixationXEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.FixationXEditFieldLabel.Text = 'Fixation X';
            app.FixationXEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'numeric');
            app.ELDummySwitchLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.ELDummySwitchLabel.Text = 'EL Dummy';
            app.ELDummySwitch = uiswitch(app.EquiMotionTaskGeneralPanel, 'rocker');
            app.DebugModeSwitchLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.EquiMotionTaskGeneralPanel, 'rocker');
            app.FixationYEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.FixationYEditFieldLabel.Text = 'Fixation Y';
            app.FixationYEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'numeric');
            app.FixInitiationEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.FixInitiationEditFieldLabel.Text = 'Fix Initiation';
            app.FixInitiationEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'numeric');
            app.FixInitiationEditField.Value = 1;
            app.Fix1stDurationEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.Fix1stDurationEditFieldLabel.Text = 'Fix 1st Duration';
            app.Fix1stDurationEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'numeric');
            app.Fix1stDurationEditField.Limits = [0.1 20];
            app.Fix1stDurationEditField.Value = 1;
            app.FixationDiameterEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.FixationDiameterEditFieldLabel.Text = 'Fixation Diameter';
            app.FixationDiameterEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'numeric');
            app.FixationDiameterEditField.Limits = [0.1 20];
            app.FixationDiameterEditField.Value = 3;
            app.InfoEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'text');
            app.SubjectEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'text');
            app.SubjectEditField.Value = 'Joanna Doe';
            app.SavePathEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/IsoLum/';
            app.CalibrationEditFieldLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.EquiMotionTaskGeneralPanel, 'text');
            app.CButton = uibutton(app.EquiMotionTaskGeneralPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = '...';
            app.SPButton = uibutton(app.EquiMotionTaskGeneralPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Text = '...';
            app.SendTriggersSwitchLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.SendTriggersSwitchLabel.Text = 'Send Triggers';
            app.SendTriggersSwitch = uiswitch(app.EquiMotionTaskGeneralPanel, 'rocker');
            app.ColorDepthDropDownLabel = uilabel(app.EquiMotionTaskGeneralPanel);
            app.ColorDepthDropDownLabel.Text = 'Color Depth';
            app.ColorDepthDropDown = uidropdown(app.EquiMotionTaskGeneralPanel);
            app.ColorDepthDropDown.Items = {'FloatingPoint32BitIfPossible', 'FloatingPoint32Bit', '8bit', 'EnableBits++Bits++Output', 'EnableBits++Color++Output', 'EnableBits++Mono++Output', 'EnableNative10BitFramebuffer', '', '', '', ''};
            app.ColorDepthDropDown.Value = 'FloatingPoint32BitIfPossible';
            app.StimulusOptionsPanel = uipanel(app.SettingsTab);
            app.StimulusOptionsPanel.Title = 'Stimulus Options';
            app.FixedColorEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.FixedColorEditFieldLabel.Text = 'Fixed Color';
            app.FixedColorEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.FixedColorEditField.Value = '[ 0.3 0 0 ]';
            app.VarColorStartEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.VarColorStartEditFieldLabel.Text = 'Var Color Start';
            app.VarColorStartEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.VarColorStartEditField.Value = '[ 0 0.1 0 ]';
            app.VarColorEndEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.VarColorEndEditFieldLabel.Text = 'Var Color End';
            app.VarColorEndEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.VarColorEndEditField.Value = '[ 0 0.5 0 ]';
            app.VarColorStepsEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.VarColorStepsEditFieldLabel.Text = 'Var Color Steps';
            app.VarColorStepsEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.VarColorStepsEditField.Value = '10';
            app.SizeEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.SizeEditField.Limits = [1 100];
            app.SizeEditField.Value = 20;
            app.TrialDurationsEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.TrialDurationsEditFieldLabel.Text = 'Trial Duration (s)';
            app.TrialDurationsEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.TrialDurationsEditField.Limits = [1 40];
            app.TrialDurationsEditField.Value = 3.5;
            app.BackgroundColorEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.BackgroundColorEditField.Value = '[ 0.5 0.5 0.5 ]';
            app.FrequencyEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.FrequencyEditField.Limits = [0 40];
            app.FrequencyEditField.ValueDisplayFormat = '%11.5g';
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 1.754;
            app.TrialNumberEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.TrialNumberEditFieldLabel.Text = 'Trial Number';
            app.TrialNumberEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.TrialNumberEditField.Limits = [1 40];
            app.TrialNumberEditField.Value = 5;
            app.SmoothingEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.SmoothingEditField.Limits = [1 100];
            app.SmoothingEditField.Value = 60;
            app.sigma1 = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.sigma1.Limits = [0 501];
            app.sigma1.Enable = 'off';
            app.sigma1.Value = 11;
            app.sigma2 = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.sigma2.Limits = [0 501];
            app.sigma2.Enable = 'off';
            app.IntertrialIntervalsLabel = uilabel(app.StimulusOptionsPanel);
            app.IntertrialIntervalsLabel.Text = 'Intertrial Interval (s)';
            app.IntertrialIntervalsEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.IntertrialIntervalsEditField.Limits = [1 40];
            app.IntertrialIntervalsEditField.Value = 3;
            app.ControlPanel = uipanel(app.SettingsTab);
            app.ControlPanel.Title = 'Control';
            app.GOButton = uibutton(app.ControlPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.ExitButton = uibutton(app.ControlPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit!';
            app.ExperimentalRunTab = uitab(app.TabGroup);
            app.ExperimentalRunTab.Title = 'Experimental Run';
            app.IsoLumAxes = uiaxes(app.ExperimentalRunTab);
            title(app.IsoLumAxes, 'Raw Pupil Data')
            xlabel(app.IsoLumAxes, 'Time (s)')
            ylabel(app.IsoLumAxes, 'Pupil Diameter')
            app.IsoLumAxesPower = uiaxes(app.ExperimentalRunTab);
            title(app.IsoLumAxesPower, 'Power Per Trial')
            xlabel(app.IsoLumAxesPower, 'Trial')
            ylabel(app.IsoLumAxesPower, 'Power')
            app.IsoLumAxesAverage = uiaxes(app.ExperimentalRunTab);
            title(app.IsoLumAxesAverage, 'Power per Variable')
            xlabel(app.IsoLumAxesAverage, 'Variable')
            ylabel(app.IsoLumAxesAverage, 'Power')
            app.MoreSettingsTab = uitab(app.TabGroup);
            app.MoreSettingsTab.Title = 'More Settings...';
            app.EyelinkPanel = uipanel(app.MoreSettingsTab);
            app.EyelinkPanel.Title = 'Eyelink';
            app.SampleRateKnobLabel = uilabel(app.EyelinkPanel);
            app.SampleRateKnobLabel.Text = 'Sample Rate';
            app.SampleRateKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.SampleRateKnob.Items = {'250', '500', '1000', '2000'};
            app.SampleRateKnob.Value = '1000';
            app.CalibPointsKnobLabel = uilabel(app.EyelinkPanel);
            app.CalibPointsKnobLabel.Text = 'Calib Points';
            app.CalibPointsKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.CalibPointsKnob.Items = {'HV3', 'HV5', 'HV9', 'HV13'};
            app.CalibPointsKnob.Value = 'HV5';
            app.TargetFixationOptionsPanel = uipanel(app.MoreSettingsTab);
            app.TargetFixationOptionsPanel.Title = 'Target Fixation Options';
            app.TargetIntiationEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetIntiationEditFieldLabel.Enable = 'off';
            app.TargetIntiationEditFieldLabel.Text = 'Target Intiation';
            app.TargetIntiationEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetIntiationEditField.Limits = [0.01 20];
            app.TargetIntiationEditField.Enable = 'off';
            app.TargetIntiationEditField.Value = 0.5;
            app.TargetMaintainEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetMaintainEditFieldLabel.Enable = 'off';
            app.TargetMaintainEditFieldLabel.Text = 'Target Maintain';
            app.TargetMaintainEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetMaintainEditField.Limits = [0.01 20];
            app.TargetMaintainEditField.Enable = 'off';
            app.TargetMaintainEditField.Value = 0.5;
            app.TargetDiameterEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetDiameterEditFieldLabel.Enable = 'off';
            app.TargetDiameterEditFieldLabel.Text = 'Target Diameter';
            app.TargetDiameterEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetDiameterEditField.Limits = [0.1 20];
            app.TargetDiameterEditField.Enable = 'off';
            app.TargetDiameterEditField.Value = 6;
            app.OthertrainingparametersPanel = uipanel(app.MoreSettingsTab);
            app.OthertrainingparametersPanel.Title = 'Other training parameters';
            app.InterTrialDelayEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.InterTrialDelayEditFieldLabel.Enable = 'off';
            app.InterTrialDelayEditFieldLabel.Text = 'InterTrial Delay';
            app.InterTrialDelayEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.InterTrialDelayEditField.Limits = [0.01 20];
            app.InterTrialDelayEditField.Enable = 'off';
            app.InterTrialDelayEditField.Value = 0.75;
            app.FixOnlyRewardEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.FixOnlyRewardEditFieldLabel.Enable = 'off';
            app.FixOnlyRewardEditFieldLabel.Text = '% Fix Only Reward';
            app.FixOnlyRewardEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.FixOnlyRewardEditField.Limits = [0 100];
            app.FixOnlyRewardEditField.RoundFractionalValues = 'on';
            app.FixOnlyRewardEditField.ValueDisplayFormat = '%.0f';
            app.FixOnlyRewardEditField.Enable = 'off';
            app.FixOnlyRewardEditField.Value = 10;
            app.ExclusionZoneEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.ExclusionZoneEditFieldLabel.Enable = 'off';
            app.ExclusionZoneEditFieldLabel.Text = 'Exclusion Zone';
            app.ExclusionZoneEditField = uieditfield(app.OthertrainingparametersPanel, 'text');
            app.ExclusionZoneEditField.Enable = 'off';
            app.ExclusionZoneEditField.Value = '[ ]';
            app.ArduinoPanel = uipanel(app.MoreSettingsTab);
            app.ArduinoPanel.Title = 'Arduino';
            app.SerialPortNameEditFieldLabel = uilabel(app.ArduinoPanel);
            app.SerialPortNameEditFieldLabel.Text = 'Serial Port Name';
            app.SerialPortNameEditField = uieditfield(app.ArduinoPanel, 'text');
            app.SerialPortNameEditField.Value = '/dev/ttyACM1';
            app.SendRewardSwitchLabel = uilabel(app.ArduinoPanel);
            app.SendRewardSwitchLabel.Text = 'Send Reward?';
            app.SendRewardSwitch = uiswitch(app.ArduinoPanel, 'rocker');
            % Show the figure after all components are created
            app.EquiMotionTaskUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EquiMotion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EquiMotionTaskUIFigure)

            % Execute the startup function
            runStartupFcn(app, @setPath)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EquiMotionTaskUIFigure)
        end
    end
end