classdef IsoLum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsoLuminanceTesterUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GeneralPanel                    matlab.ui.container.Panel
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel       matlab.ui.control.Label
        PixelsPerCmEditField            matlab.ui.control.NumericEditField
        FixationXEditFieldLabel         matlab.ui.control.Label
        FixationXEditField              matlab.ui.control.NumericEditField
        ELDummySwitchLabel              matlab.ui.control.Label
        ELDummySwitch                   matlab.ui.control.RockerSwitch
        DebugModeSwitchLabel            matlab.ui.control.Label
        DebugModeSwitch                 matlab.ui.control.RockerSwitch
        FixationYEditFieldLabel         matlab.ui.control.Label
        FixationYEditField              matlab.ui.control.NumericEditField
        FixInitiationEditFieldLabel     matlab.ui.control.Label
        FixInitiationEditField          matlab.ui.control.NumericEditField
        Fix1stDurationEditFieldLabel    matlab.ui.control.Label
        Fix1stDurationEditField         matlab.ui.control.NumericEditField
        FixationDiameterEditFieldLabel  matlab.ui.control.Label
        FixationDiameterEditField       matlab.ui.control.NumericEditField
        InfoEditFieldLabel              matlab.ui.control.Label
        InfoEditField                   matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        CalibrationEditFieldLabel       matlab.ui.control.Label
        CalibrationEditField            matlab.ui.control.EditField
        CButton                         matlab.ui.control.Button
        SPButton                        matlab.ui.control.Button
        StimulusOptionsPanel            matlab.ui.container.Panel
        FixedColorEditFieldLabel        matlab.ui.control.Label
        FixedColorEditField             matlab.ui.control.EditField
        VarColorStartEditFieldLabel     matlab.ui.control.Label
        VarColorStartEditField          matlab.ui.control.EditField
        VarColorEndEditFieldLabel       matlab.ui.control.Label
        VarColorEndEditField            matlab.ui.control.EditField
        VarColorStepsEditFieldLabel     matlab.ui.control.Label
        VarColorStepsEditField          matlab.ui.control.EditField
        Circle1DiameterEditFieldLabel   matlab.ui.control.Label
        Circle1DiameterEditField        matlab.ui.control.NumericEditField
        TrialDurationsEditFieldLabel    matlab.ui.control.Label
        TrialDurationsEditField         matlab.ui.control.NumericEditField
        BackgroundColorEditFieldLabel   matlab.ui.control.Label
        BackgroundColorEditField        matlab.ui.control.EditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        TrialNumberEditFieldLabel       matlab.ui.control.Label
        TrialNumberEditField            matlab.ui.control.NumericEditField
        Circle2DiameterEditFieldLabel   matlab.ui.control.Label
        Circle2DiameterEditField        matlab.ui.control.NumericEditField
        sigma1                          matlab.ui.control.NumericEditField
        sigma2                          matlab.ui.control.NumericEditField
        IntertrialIntervalsLabel        matlab.ui.control.Label
        IntertrialIntervalsEditField    matlab.ui.control.NumericEditField
        ControlPanel                    matlab.ui.container.Panel
        GOButton                        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        ExperimentalRunTab              matlab.ui.container.Tab
        IsoLumAxes                      matlab.ui.control.UIAxes
        IsoLumAxesPower                 matlab.ui.control.UIAxes
    end

 
 properties (Access = public)
  ana % parsed settings
 end
 
 methods (Access = private)
  
  function parseSettings(app)
       app.ana = [];
       app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
       app.ana.subject = app.SubjectEditField.Value;
       app.ana.comments = app.InfoEditField.Value;
       app.ana.ResultDir = app.SavePathEditField.Value;
       app.ana.gammaTable = app.CalibrationEditField.Value;
       app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
       app.ana.distance = app.DistanceEditField.Value;
       app.ana.fixX = app.FixationXEditField.Value;
       app.ana.fixY = app.FixationYEditField.Value;
       app.ana.firstFixInit = app.FixInitiationEditField.Value;
       app.ana.firstFixDiameter = app.FixationDiameterEditField.Value;
       app.ana.firstFixTime = app.Fix1stDurationEditField.Value;
       app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value);
       app.ana.colorFixed = str2num(app.FixedColorEditField.Value);
       app.ana.colorStart = str2num(app.VarColorStartEditField.Value);
       app.ana.colorEnd = str2num(app.VarColorEndEditField.Value);
       app.ana.colorStep = str2num(app.VarColorStepsEditField.Value);
       app.ana.circleDiameter = app.Circle1DiameterEditField.Value;
       app.ana.backgroundDiameter = app.Circle2DiameterEditField.Value;
       app.ana.sigma1 = app.sigma1.Value;
       app.ana.sigma2 = app.sigma2.Value;
       app.ana.trialDuration = app.TrialDurationsEditField.Value;
       app.ana.trialInterval = app.IntertrialIntervalsEditField.Value;
       app.ana.trialNumber = app.TrialNumberEditField.Value;
       app.ana.frequency = app.FrequencyEditField.Value;
       if strcmpi(app.DebugModeSwitch.Value,'On')
        app.ana.debug = true;
        app.ana.windowed = [0 0 1000 1000];
       else
        app.ana.debug = false;
        app.ana.windowed = [];
       end
       if strcmpi(app.ELDummySwitch.Value,'On')
        app.ana.isDummy = true;
       else
        app.ana.isDummy = false;
       end
       app.ana.plotAxis1 = app.IsoLumAxes;
       app.ana.plotAxis2 = app.IsoLumAxesPower;
  end

 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setPath(app)
            s=screenManager();
            app.SavePathEditField.Value=s.paths.savedData;
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            
            [f, p] = uigetfile('*.mat','Select a Calibration File');
            app.CalibrationEditField.Value = [p f];
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           delete(app);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           runIsoluminant(app.ana)
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
            p=app.setPath();
            cd(p);
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.parseSettings();
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IsoLuminanceTesterUIFigure = uifigure('Visible', 'off');
            app.IsoLuminanceTesterUIFigure.Name = 'IsoLuminance Tester';
            app.TabGroup = uitabgroup(app.IsoLuminanceTesterUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GeneralPanel = uipanel(app.SettingsTab);
            app.GeneralPanel.Title = 'General';
            app.DistanceEditFieldLabel = uilabel(app.GeneralPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.DistanceEditField.Value = 64;
            app.PixelsPerCmEditFieldLabel = uilabel(app.GeneralPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.PixelsPerCmEditField.Value = 36;
            app.FixationXEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixationXEditFieldLabel.Text = 'Fixation X';
            app.FixationXEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.ELDummySwitchLabel = uilabel(app.GeneralPanel);
            app.ELDummySwitchLabel.Text = 'EL Dummy';
            app.ELDummySwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.DebugModeSwitchLabel = uilabel(app.GeneralPanel);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.FixationYEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixationYEditFieldLabel.Text = 'Fixation Y';
            app.FixationYEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.FixInitiationEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixInitiationEditFieldLabel.Text = 'Fix Initiation';
            app.FixInitiationEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.FixInitiationEditField.Value = 1;
            app.Fix1stDurationEditFieldLabel = uilabel(app.GeneralPanel);
            app.Fix1stDurationEditFieldLabel.Text = 'Fix 1st Duration';
            app.Fix1stDurationEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.Fix1stDurationEditField.Limits = [0.1 20];
            app.Fix1stDurationEditField.Value = 1;
            app.FixationDiameterEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixationDiameterEditFieldLabel.Text = 'Fixation Diameter';
            app.FixationDiameterEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.FixationDiameterEditField.Limits = [0.1 20];
            app.FixationDiameterEditField.Value = 3;
            app.InfoEditFieldLabel = uilabel(app.GeneralPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.GeneralPanel, 'text');
            app.SubjectEditFieldLabel = uilabel(app.GeneralPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.GeneralPanel, 'text');
            app.SubjectEditField.Value = 'John Doe';
            app.SavePathEditFieldLabel = uilabel(app.GeneralPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.GeneralPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/IsoLum/';
            app.CalibrationEditFieldLabel = uilabel(app.GeneralPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.GeneralPanel, 'text');
            app.CButton = uibutton(app.GeneralPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = '...';
            app.SPButton = uibutton(app.GeneralPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Text = '...';
            app.StimulusOptionsPanel = uipanel(app.SettingsTab);
            app.StimulusOptionsPanel.Title = 'Stimulus Options';
            app.FixedColorEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.FixedColorEditFieldLabel.Text = 'Fixed Color';
            app.FixedColorEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.FixedColorEditField.Value = '[ 0.3 0 0 ]';
            app.VarColorStartEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.VarColorStartEditFieldLabel.Text = 'Var Color Start';
            app.VarColorStartEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.VarColorStartEditField.Value = '[ 0 0 0 ]';
            app.VarColorEndEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.VarColorEndEditFieldLabel.Text = 'Var Color End';
            app.VarColorEndEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.VarColorEndEditField.Value = '[ 0 0.5 0 ]';
            app.VarColorStepsEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.VarColorStepsEditFieldLabel.Text = 'Var Color Steps';
            app.VarColorStepsEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.VarColorStepsEditField.Value = '10';
            app.Circle1DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Circle1DiameterEditFieldLabel.Text = 'Circle 1 Diameter';
            app.Circle1DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.Circle1DiameterEditField.Limits = [1 100];
            app.Circle1DiameterEditField.Value = 6;
            app.TrialDurationsEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.TrialDurationsEditFieldLabel.Text = 'Trial Duration (s)';
            app.TrialDurationsEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.TrialDurationsEditField.Limits = [1 40];
            app.TrialDurationsEditField.Value = 3.5;
            app.BackgroundColorEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.BackgroundColorEditField.Value = '[ 0.3 0 0 ]';
            app.FrequencyEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.FrequencyEditField.Limits = [0 40];
            app.FrequencyEditField.ValueDisplayFormat = '%11.5g';
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 0.57;
            app.TrialNumberEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.TrialNumberEditFieldLabel.Text = 'Trial Number';
            app.TrialNumberEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.TrialNumberEditField.Limits = [1 40];
            app.TrialNumberEditField.Value = 5;
            app.Circle2DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Circle2DiameterEditFieldLabel.Text = 'Circle 2 Diameter';
            app.Circle2DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.Circle2DiameterEditField.Limits = [1 100];
            app.Circle2DiameterEditField.Value = 10;
            app.sigma1 = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.sigma1.Limits = [0 501];
            app.sigma1.Value = 11;
            app.sigma2 = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.sigma2.Limits = [0 501];
            app.sigma2.Value = 101;
            app.IntertrialIntervalsLabel = uilabel(app.StimulusOptionsPanel);
            app.IntertrialIntervalsLabel.Text = 'Intertrial Interval (s)';
            app.IntertrialIntervalsEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.IntertrialIntervalsEditField.Limits = [1 40];
            app.IntertrialIntervalsEditField.Value = 1.75;
            app.ControlPanel = uipanel(app.SettingsTab);
            app.ControlPanel.Title = 'Control';
            app.GOButton = uibutton(app.ControlPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.ExitButton = uibutton(app.ControlPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit!';
            app.ExperimentalRunTab = uitab(app.TabGroup);
            app.ExperimentalRunTab.Title = 'Experimental Run';
            app.IsoLumAxes = uiaxes(app.ExperimentalRunTab);
            title(app.IsoLumAxes, 'Raw Pupil Data')
            xlabel(app.IsoLumAxes, 'Time (s)')
            ylabel(app.IsoLumAxes, 'Pupil Diameter (°)')
            app.IsoLumAxesPower = uiaxes(app.ExperimentalRunTab);
            title(app.IsoLumAxesPower, 'Power Per Trial')
            xlabel(app.IsoLumAxesPower, 'Trial')
            ylabel(app.IsoLumAxesPower, 'Power')
            % Show the figure after all components are created
            app.IsoLuminanceTesterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IsoLum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IsoLuminanceTesterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @setPath)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsoLuminanceTesterUIFigure)
        end
    end
end